classdef chess_reader < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        MoveSpinnerLabel  matlab.ui.control.Label
        moveEntry         matlab.ui.control.Spinner
        turnLable         matlab.ui.control.Label
        loadButton        matlab.ui.control.Button
        loadLable         matlab.ui.control.Label
    end


    properties (Access = private)
        board % Fresh clean board
        gameData % All move information for current game
        totMoves % Total number of moves made in current game after inital boardstate
        enumPiece % Order pieces appear in move info
    end

    methods (Access = private)
    
        function setMove(app,val) 
            fprintf("Set Move - Setting to value: %d\n",val);
            app.moveEntry.Value = val;          
        end
        
        function setMoveLim(app,max)
            fprintf("Set Move Limit - Setting to value: %d\n",max);
            if max
                app.moveEntry.Limits(2) = max;
            else
                app.moveEntry.Enable = 'off';
            end
        end
        
        function genBoard(app)
            white = ones(100);
            black = white.*13/16;
            
            u = [white black;black white];
            two_row = [u u u u];
            app.board = [two_row; two_row; two_row; two_row];
        end
        
        function toMove(app,move)
            disp("To Move - Determining move")
            if mod(move,2) == 0
                app.turnLable.Text = "White to Move";
            else
                app.turnLable.Text = "Black To Move";
            end
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            disp("Start up - GUI Successfully Initalized")
            app.UIFigure.Name = "CR Controller";
            app.UIFigure.Resize = 'off';
            
            disp("Start up - Loading Icons")
            loadIcons();
            
            disp("Start up - Generating Board")
            app.genBoard();
            
            disp("Start up - Inializing Figure")
            win = figure();
            win.Name = "Chess Reader";
            win.NumberTitle = 'off';
            win.Resize = 'off';
            win.ToolBar = 'none';
            win.MenuBar = 'none';
            
            app.enumPiece = {whitePawn whiteRook whiteNight whiteBishop whiteQueen whiteKing blackPawn blackRook blackNight blackBishop blackQueen blackKing};
            imshow(app.board)
        end

        % Button pushed function: loadButton
        function loadButtonPushed(app, event)
            [loadfile,path] = uigetfile('../../games/*.txt');
            if loadfile == 0
                disp("Load Button - No file selected.")
                return
            end
            
            fprintf('Load Button - File Name: %s', loadfile)
            fprintf('Load Button - File Path: %s', path)
            app.loadLable.Text = ['Viewing game ', loadfile(5:end-4)];
            
            disp('Load Button - Loading in game data')
            [app.gameData,app.totMoves] = readGame([path loadfile]);
            
            disp('Load Button - Set move entry')
            app.moveEntry.Enable = 'on';
            app.setMove(0);
            app.setMoveLim(app.totMoves);
            app.moveEntryValueChanged()
            
        end

        % Value changed function: moveEntry
        function moveEntryValueChanged(app, event)
            move_idx = app.moveEntry.Value + 1;
            disp("Move Value Change - Get Move Data");
            curr_pieces = app.gameData(:,move_idx);
            curr_board = app.board;
            disp("Move Value Change - Place Pieces")
            for i = 1:12
                % Vectorize all this?
                p_loc = curr_pieces(i);
                p_bin = dec2bin(p_loc,64);
                p_ones = 65-find(p_bin == '1');
                %Still looped
                for j = p_ones
                    curr_board = place(curr_board,j,app.enumPiece{i});
                end
            end
            app.toMove(app.moveEntry.Value)
            imshow(curr_board)
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.MoveSpinnerLabel = uilabel(app.UIFigure);
            app.MoveSpinnerLabel.Enable = 'off';
            app.MoveSpinnerLabel.Text = 'Move';
            app.moveEntry = uispinner(app.UIFigure);
            app.moveEntry.Limits = [0 Inf];
            app.moveEntry.ValueChangedFcn = createCallbackFcn(app, @moveEntryValueChanged, true);
            app.moveEntry.Enable = 'off';
            app.turnLable = uilabel(app.UIFigure);
            app.turnLable.Text = '';
            app.loadButton = uibutton(app.UIFigure, 'push');
            app.loadButton.ButtonPushedFcn = createCallbackFcn(app, @loadButtonPushed, true);
            app.loadButton.Text = 'Load Game';
            app.loadLable = uilabel(app.UIFigure);
            app.loadLable.Text = 'Please Select a Game File';
        end
    end

    methods (Access = public)

        % Construct app
        function app = chess_reader

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end